classdef udp_data_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        udp_port_label    matlab.ui.control.Label
        udp_data_label    matlab.ui.control.Label
        backButton        matlab.ui.control.Button
        spinnerImage      matlab.ui.control.Image
        model_link_label  matlab.ui.control.Label
        settingsButton    matlab.ui.control.Button
        seedataButton     matlab.ui.control.Button
        udp_status_label  matlab.ui.control.Label
        Image             matlab.ui.control.Image
        accAxes           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        u
        local_port = 5005;
        packet_size = 28;  % Standardwert direkt gesetzt
        time = [];
        yaw = [];
        roll = [];
        pitch = [];
        ax = [];
        ay = [];
        az = [];
        roll_line
        pitch_line
        yaw_line
        ax_line
        ay_line
        az_line
        switch_to_data = false;
        my_timer
        last_packet_time
        lost_connection = true;
        plot_colors = [79 62 140; 73 191 191; 242 193 46] / 255;
        line_width = 2.1;
        legend_font_size = 11;
        mb_recieved = 0;
        in_settings = false;
        is_closing = false;
    end

    methods (Access = private)
        function start_udp_monitor(app)
            pause(0.25)
            app.udp_status_label.Position = [1110 476 534 58];
            pause(0.5)
            app.spinnerImage.Visible = 'on';

            app.u = udpportfind;
            if isempty(app.u)
                app.u = udpport('LocalPort', app.local_port);
            else
                app.u = app.u(1);
            end

            configureCallback(app.u, 'byte', 1, @(src, evt) on_udp_data(app, src, evt));

            app.last_packet_time = datetime('now');
            app.my_timer = timer('Period',1,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.my_timer.TimerFcn = @(x,y) lost_udp_connection(app,x,y);
            start(app.my_timer);
        end

        %----------------- callback / bread and butter functions
        function on_udp_data(app, src, evt)
            if app.is_closing || ~isvalid(app)
                return
            end
            raw = read(src, app.packet_size, 'uint8');
            if numel(raw) < app.packet_size
                disp('Incomplete UDP packet');
                return
            end

            vals = typecast(uint8(raw), 'single'); % 1x7

            % app.udp_data_label.Visible = 'on';
            % app.mb_recieved = app.mb_recieved + app.packet_size * 10^-6;
            % app.udp_data_label.Text = num2str(app.mb_recieved) + 'MB of data recieved';

            app.last_packet_time = datetime('now');
            app.udp_status_label.Text = 'receving udp packages';
            if ~app.switch_to_data && ~app.in_settings;  app.seedataButton.Visible = 'on'; end
            app.lost_connection = false;

            if app.switch_to_data
                app.time  = [app.time; vals(1)];
                app.yaw  = [app.yaw; vals(2)];
                app.pitch = [app.pitch; vals(3)];
                app.roll   = [app.roll; vals(4)];
                app.ax    = [app.ax; vals(5)];
                app.ay    = [app.ay; vals(6)];
                app.az    = [app.az; vals(7)];

                if ~isempty(app.time)
                    if isempty(app.roll_line)
                        app.redraw_lines();
                    else
                        app.roll_line.XData  = app.time;  app.roll_line.YData  = app.roll;
                        app.pitch_line.XData = app.time;  app.pitch_line.YData = app.pitch;
                        app.yaw_line.XData   = app.time;  app.yaw_line.YData   = app.yaw;
                        app.ax_line.XData    = app.time;  app.ax_line.YData    = app.ax;
                        app.ay_line.XData    = app.time;  app.ay_line.YData    = app.ay;
                        app.az_line.XData    = app.time;  app.az_line.YData    = app.az;
                    end

                    xlim(app.UIAxes, [max(0, max(app.time)-8), max(app.time)]);
                    xlim(app.accAxes, [max(0, max(app.time)-8), max(app.time)]);
                end

                drawnow limitrate;
            end
        end

        function lost_udp_connection(app,x,y)
            no_connection_timer = datetime('now') - app.last_packet_time;
            secondsElapsed = seconds(no_connection_timer);
            if ~app.lost_connection && secondsElapsed >= 2
                app.lost_connection = true;
                app.switch_to_data = false;

                app.clear_lines();

                app.udp_status_label.Text = 'listening for vehicle udp sensor feed';
                app.UIAxes.Visible = 'off';
                app.accAxes.Visible = 'off';
                app.Image.Visible = 'on';
                app.model_link_label.Visible = 'on';
                app.udp_status_label.Visible = 'on';
                app.settingsButton.Visible = 'on';
                app.seedataButton.Visible = 'off';
                app.spinnerImage.Visible = 'on';
                app.backButton.Visible = 'off';
            end
        end

        %----------------- helper functions
        function clear_lines(app)
            delete(app.roll_line); app.roll_line = [];
            delete(app.pitch_line); app.pitch_line = [];
            delete(app.yaw_line); app.yaw_line = [];

            delete(app.ax_line); app.ax_line = [];
            delete(app.ay_line); app.ay_line = [];
            delete(app.az_line); app.az_line = [];

            % cla(app.UIAxes,'reset');
            % cla(app.accAxes, 'reset');

            hold(app.UIAxes,'off');
            hold(app.accAxes,'off');

            app.time  = [];
            app.roll  = [];
            app.pitch = [];
            app.yaw   = [];
            app.ax    = [];
            app.ay    = [];
            app.az   = [];
        end

        function redraw_lines(app)
            app.roll_line  = plot(app.UIAxes, app.time, app.roll,  'Color', app.plot_colors(1,:), 'LineWidth', app.line_width); hold(app.UIAxes,'on');
            app.pitch_line = plot(app.UIAxes, app.time, app.pitch, 'Color', app.plot_colors(2,:), 'LineWidth', app.line_width);
            app.yaw_line   = plot(app.UIAxes, app.time, app.yaw,   'Color', app.plot_colors(3,:), 'LineWidth', app.line_width);
            legend(app.UIAxes, {'roll','pitch','yaw'},  'Location', 'northeastoutside','Box','off','FontSize',app.legend_font_size);
            hold(app.UIAxes,'off');

            app.ax_line = plot(app.accAxes, app.time, app.ax, 'Color', app.plot_colors(2,:), 'LineWidth', app.line_width); hold(app.accAxes,'on');
            app.ay_line = plot(app.accAxes, app.time, app.ay, 'Color', app.plot_colors(1,:), 'LineWidth', app.line_width);
            app.az_line   = plot(app.accAxes, app.time, app.az, 'Color', app.plot_colors(3,:), 'LineWidth', app.line_width);
            legend(app.accAxes, {'ax','ay','az'}, 'Location', 'northeastoutside', 'Box','off','FontSize',app.legend_font_size);
            hold(app.accAxes,'off');

            grid(app.UIAxes, 'on');
            grid(app.accAxes, 'on');
            xlabel(app.accAxes, 'time in s');
            xlabel(app.UIAxes, ' ');
            ylabel(app.UIAxes, 'angle in deg');
            ylabel(app.accAxes, 'acceleration in m/s^2');
            title(app.UIAxes, 'udp live data');
            axis(app.UIAxes, 'auto');
        end

        function start_animation(app)
            app.UIAxes.Visible = 'off';
            app.accAxes.Visible = 'off';
            app.backButton.Visible = 'off';

            app.Image.Visible = 'off';
            app.udp_status_label.Visible = 'off';
            app.settingsButton.Visible = 'off';
            app.seedataButton.Visible = 'off';
            app.model_link_label.Visible = 'off';
            app.spinnerImage.Visible = 'off';
            app.udp_port_label.Visible = 'off';
            app.udp_data_label.Visible = 'off';

            pause(1)
            app.Image.Visible = 'on';
            app.model_link_label.Visible = 'on';
            pause(0.25)
            app.udp_status_label.Position = [1062 476 534 58];
            app.udp_status_label.Visible = 'on';
            pause(0.25)
            app.udp_port_label.Visible = 'on';
            pause(0.25)
            app.settingsButton.Visible = 'on';
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            start_animation(app)
            start_udp_monitor(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.is_closing = true;

            if ~isempty(app.my_timer) && isvalid(app.my_timer)
                stop(app.my_timer);
                delete(app.my_timer);
            end

            if ~isempty(app.u) && isvalid(app.u)
                configureCallback(app.u, "off");  % <<< WICHTIG
                clear app.u
            end

            delete(app)
        end

        % Button pushed function: seedataButton
        function see_data_button(app, event)
            app.switch_to_data = true;

            app.Image.Visible = 'off';
            app.UIAxes.Visible = 'on';
            app.accAxes.Visible = 'on';
            app.backButton.Visible = 'on';
            app.udp_status_label.Visible = 'off';
            app.settingsButton.Visible = 'off';
            app.seedataButton.Visible = 'off';
            app.model_link_label.Visible = 'off';
            app.spinnerImage.Visible = 'off';
            app.udp_port_label.Visible = 'off';
        end

        % Button pushed function: settingsButton
        function settingsButtonPushed(app, event)
            app.udp_status_label.Visible = 'off';
            app.settingsButton.Visible = 'off';
            app.seedataButton.Visible = 'off';
            app.model_link_label.Visible = 'off';
            app.udp_port_label.Visible = 'off';
            app.Image.Visible = 'off';
            app.seedataButton.Visible = 'off';
            app.spinnerImage.Visible = 'off';

            app.backButton.Visible = 'on';

            app.in_settings = true;
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            app.switch_to_data = false;
            app.in_settings = false;

            app.clear_lines();

            if ~isempty(app.u)
                flush(app.u);
            end

            app.UIAxes.Visible = 'off';
            app.accAxes.Visible = 'off';
            app.backButton.Visible = 'off';

            app.Image.Visible = 'on';
            app.model_link_label.Visible = 'on';
            app.udp_status_label.Visible = 'on';
            app.settingsButton.Visible = 'on';
            app.seedataButton.Visible = 'on';
            app.spinnerImage.Visible = 'on';
            app.udp_port_label.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.accAxes = uiaxes(app.UIFigure);
            title(app.accAxes, 'Title')
            xlabel(app.accAxes, 'X')
            ylabel(app.accAxes, 'Y')
            zlabel(app.accAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'vehicle.png');
            app.udp_status_label = uilabel(app.UIFigure);
            app.udp_status_label.Text = 'listening for vehicle udp sensor feed...';
            app.seedataButton = uibutton(app.UIFigure, 'push');
            app.seedataButton.ButtonPushedFcn = createCallbackFcn(app, @see_data_button, true);
            app.seedataButton.Text = 'see data';
            app.settingsButton = uibutton(app.UIFigure, 'push');
            app.settingsButton.ButtonPushedFcn = createCallbackFcn(app, @settingsButtonPushed, true);
            app.settingsButton.Icon = fullfile(pathToMLAPP, 'images', 'icons8-settings-500.png');
            app.settingsButton.Text = '';
            app.model_link_label = uilabel(app.UIFigure);
            app.model_link_label.Text = 'https://sketchfab.com/3d-models/bmw-m4-widebody-wwwvecarzcom-a5687befab084efdba9a5c99a9a8ad4c';
            app.spinnerImage = uiimage(app.UIFigure);
            app.spinnerImage.ImageSource = fullfile(pathToMLAPP, 'images', 'spinner.gif');
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = 'back';
            app.udp_data_label = uilabel(app.UIFigure);
            app.udp_data_label.Text = '0.00 MB of data recieved';
            app.udp_port_label = uilabel(app.UIFigure);
            app.udp_port_label.Text = 'local port 5005';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = udp_data_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end